classdef audio_gui_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        EffectsandModificationsPanel  matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        GridLayout22                  matlab.ui.container.GridLayout
        GridLayout23                  matlab.ui.container.GridLayout
        Slider_7                      matlab.ui.control.Slider
        Slider_6                      matlab.ui.control.Slider
        Slider_5                      matlab.ui.control.Slider
        Slider_4                      matlab.ui.control.Slider
        Slider_3                      matlab.ui.control.Slider
        Slider_2                      matlab.ui.control.Slider
        kHzLabel_2                    matlab.ui.control.Label
        kHzLabel                      matlab.ui.control.Label
        HzLabel_4                     matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        EqualizerLabel                matlab.ui.control.Label
        GridLayout20                  matlab.ui.container.GridLayout
        GridLayout21_2                matlab.ui.container.GridLayout
        FeedbackKnob                  matlab.ui.control.Knob
        FeedbackKnobLabel             matlab.ui.control.Label
        GridLayout21                  matlab.ui.container.GridLayout
        DelayKnob                     matlab.ui.control.Knob
        DelayKnobLabel                matlab.ui.control.Label
        DelayandEchoLabel             matlab.ui.control.Label
        GridLayout19                  matlab.ui.container.GridLayout
        Slider                        matlab.ui.control.Slider
        PlaybackSpeedLabel            matlab.ui.control.Label
        GridLayout18                  matlab.ui.container.GridLayout
        Knob                          matlab.ui.control.Knob
        VolumeLabel                   matlab.ui.control.Label
        DirectionLabel                matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        GridLayout12_3                matlab.ui.container.GridLayout
        SaveButton_3                  matlab.ui.control.Button
        DropDown_3                    matlab.ui.control.DropDown
        GridLayout15                  matlab.ui.container.GridLayout
        SynthesizerPanel              matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        WaveFunctionButtonGroup       matlab.ui.container.ButtonGroup
        SquareButton                  matlab.ui.control.RadioButton
        TriangleButton                matlab.ui.control.RadioButton
        SineButton                    matlab.ui.control.RadioButton
        GridLayout12_2                matlab.ui.container.GridLayout
        SaveButton_2                  matlab.ui.control.Button
        DropDown_2                    matlab.ui.control.DropDown
        SamplesPanel                  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3_9                 matlab.ui.container.GridLayout
        Load9Button                   matlab.ui.control.Button
        Sample9Button                 matlab.ui.control.Button
        GridLayout3_8                 matlab.ui.container.GridLayout
        Load8Button                   matlab.ui.control.Button
        Sample8Button                 matlab.ui.control.Button
        GridLayout3_7                 matlab.ui.container.GridLayout
        Load7Button                   matlab.ui.control.Button
        Sample7Button                 matlab.ui.control.Button
        GridLayout3_6                 matlab.ui.container.GridLayout
        Load6Button                   matlab.ui.control.Button
        Sample6Button                 matlab.ui.control.Button
        GridLayout3_5                 matlab.ui.container.GridLayout
        Load5Button                   matlab.ui.control.Button
        Sample5Button                 matlab.ui.control.Button
        GridLayout3_4                 matlab.ui.container.GridLayout
        Load4Button                   matlab.ui.control.Button
        Sample4Button                 matlab.ui.control.Button
        GridLayout3_3                 matlab.ui.container.GridLayout
        Load3Button                   matlab.ui.control.Button
        Sample3Button                 matlab.ui.control.Button
        GridLayout3_2                 matlab.ui.container.GridLayout
        Load2Button                   matlab.ui.control.Button
        Sample2Button                 matlab.ui.control.Button
        GridLayout3                   matlab.ui.container.GridLayout
        Load1Button                   matlab.ui.control.Button
        Sample1Button                 matlab.ui.control.Button
        GridLayout14                  matlab.ui.container.GridLayout
        VisualizationsPanel           matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        ChoppingPanel                 matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        GridLayout13_2                matlab.ui.container.GridLayout
        EndSlider                     matlab.ui.control.Slider
        EndSliderLabel                matlab.ui.control.Label
        GridLayout13                  matlab.ui.container.GridLayout
        StartSlider                   matlab.ui.control.Slider
        StartSliderLabel              matlab.ui.control.Label
        GridLayout12                  matlab.ui.container.GridLayout
        SaveButton                    matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        sample1Data % Property to hold audio sample1 data itself
        sample2Data % Property to hold audio sample2 data itself
        sample3Data % Property to hold audio sample3 data itself
        sample4Data % Property to hold audio sample4 data itself
        sample5Data % Property to hold audio sample5 data itself
        sample6Data % Property to hold audio sample6 data itself
        sample7Data % Property to hold audio sample7 data itself
        sample8Data % Property to hold audio sample8 data itself
        sample9Data % Property to hold audio sample9 data itself

        sample1Rate % Property to hold sample rate of sample1, needed for audio player
        sample2Rate % Property to hold sample rate of sample2, needed for audio player
        sample3Rate % Property to hold sample rate of sample3, needed for audio player
        sample4Rate % Property to hold sample rate of sample4, needed for audio player
        sample5Rate % Property to hold sample rate of sample5, needed for audio player
        sample6Rate % Property to hold sample rate of sample6, needed for audio player
        sample7Rate % Property to hold sample rate of sample7, needed for audio player
        sample8Rate % Property to hold sample rate of sample8, needed for audio player
        sample9Rate % Property to hold sample rate of sample9, needed for audio player
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load1Button
        function Load1ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample1Data, app.sample1Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            if isempty(app.sample1Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample1Data, app.sample1Rate);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            if isempty(app.sample2Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample2Data, app.sample2Rate);
        end

        % Button pushed function: Load2Button
        function Load2ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample2Data, app.sample2Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            if isempty(app.sample3Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample3Data, app.sample3Rate);
        end

        % Button pushed function: Load3Button
        function Load3ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample3Data, app.sample3Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            if isempty(app.sample4Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample4Data, app.sample4Rate);
        end

        % Button pushed function: Load4Button
        function Load4ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample4Data, app.sample4Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            if isempty(app.sample5Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample5Data, app.sample5Rate);
        end

        % Button pushed function: Load5Button
        function Load5ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample5Data, app.sample5Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            if isempty(app.sample6Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample6Data, app.sample6Rate);
        end

        % Button pushed function: Load6Button
        function Load6ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample6Data, app.sample6Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            if isempty(app.sample7Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample7Data, app.sample7Rate);
        end

        % Button pushed function: Load7Button
        function Load7ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample7Data, app.sample7Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            if isempty(app.sample8Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample8Data, app.sample8Rate);
        end

        % Button pushed function: Load8Button
        function Load8ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample8Data, app.sample8Rate] = audioread(fullfile(path, file));
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            if isempty(app.sample9Data)
                return; % Do nothing if the audio is empty
            end
            
            % Play the audio file
            sound(app.sample9Data, app.sample9Rate);
        end

        % Button pushed function: Load9Button
        function Load9ButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.flac', 'Audio Files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                return; % Make sure theres an actual file, if empty, don't import
            end

            % Read the audio file into the properties
            [app.sample9Data, app.sample9Rate] = audioread(fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout14 = uigridlayout(app.GridLayout);
            app.ChoppingPanel = uipanel(app.GridLayout14);
            app.ChoppingPanel.Title = 'Chopping';
            app.GridLayout11 = uigridlayout(app.ChoppingPanel);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.DropDown = uidropdown(app.GridLayout12);
            app.DropDown.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.DropDown.Value = 'Sample 1';
            app.SaveButton = uibutton(app.GridLayout12, 'push');
            app.SaveButton.Text = 'Save';
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.StartSliderLabel = uilabel(app.GridLayout13);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.GridLayout13);
            app.GridLayout13_2 = uigridlayout(app.GridLayout11);
            app.EndSliderLabel = uilabel(app.GridLayout13_2);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.GridLayout13_2);
            app.VisualizationsPanel = uipanel(app.GridLayout14);
            app.VisualizationsPanel.Title = 'Visualizations';
            app.GridLayout10 = uigridlayout(app.VisualizationsPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout10);
            title(app.UIAxes_2, 'Frequency Content')
            xlabel(app.UIAxes_2, 'Frequency')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout10);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout15 = uigridlayout(app.GridLayout);
            app.SamplesPanel = uipanel(app.GridLayout15);
            app.SamplesPanel.Title = 'Samples';
            app.GridLayout2 = uigridlayout(app.SamplesPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Sample1Button = uibutton(app.GridLayout3, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample 1';
            app.Load1Button = uibutton(app.GridLayout3, 'push');
            app.Load1Button.ButtonPushedFcn = createCallbackFcn(app, @Load1ButtonPushed, true);
            app.Load1Button.Text = 'Load 1';
            app.GridLayout3_2 = uigridlayout(app.GridLayout2);
            app.Sample2Button = uibutton(app.GridLayout3_2, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample 2';
            app.Load2Button = uibutton(app.GridLayout3_2, 'push');
            app.Load2Button.ButtonPushedFcn = createCallbackFcn(app, @Load2ButtonPushed, true);
            app.Load2Button.Text = 'Load 2';
            app.GridLayout3_3 = uigridlayout(app.GridLayout2);
            app.Sample3Button = uibutton(app.GridLayout3_3, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample 3';
            app.Load3Button = uibutton(app.GridLayout3_3, 'push');
            app.Load3Button.ButtonPushedFcn = createCallbackFcn(app, @Load3ButtonPushed, true);
            app.Load3Button.Text = 'Load 3';
            app.GridLayout3_4 = uigridlayout(app.GridLayout2);
            app.Sample4Button = uibutton(app.GridLayout3_4, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample 4';
            app.Load4Button = uibutton(app.GridLayout3_4, 'push');
            app.Load4Button.ButtonPushedFcn = createCallbackFcn(app, @Load4ButtonPushed, true);
            app.Load4Button.Text = 'Load 4';
            app.GridLayout3_5 = uigridlayout(app.GridLayout2);
            app.Sample5Button = uibutton(app.GridLayout3_5, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample 5';
            app.Load5Button = uibutton(app.GridLayout3_5, 'push');
            app.Load5Button.ButtonPushedFcn = createCallbackFcn(app, @Load5ButtonPushed, true);
            app.Load5Button.Text = 'Load 5';
            app.GridLayout3_6 = uigridlayout(app.GridLayout2);
            app.Sample6Button = uibutton(app.GridLayout3_6, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample 6';
            app.Load6Button = uibutton(app.GridLayout3_6, 'push');
            app.Load6Button.ButtonPushedFcn = createCallbackFcn(app, @Load6ButtonPushed, true);
            app.Load6Button.Text = 'Load 6';
            app.GridLayout3_7 = uigridlayout(app.GridLayout2);
            app.Sample7Button = uibutton(app.GridLayout3_7, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample 7';
            app.Load7Button = uibutton(app.GridLayout3_7, 'push');
            app.Load7Button.ButtonPushedFcn = createCallbackFcn(app, @Load7ButtonPushed, true);
            app.Load7Button.Text = 'Load 7';
            app.GridLayout3_8 = uigridlayout(app.GridLayout2);
            app.Sample8Button = uibutton(app.GridLayout3_8, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample 8';
            app.Load8Button = uibutton(app.GridLayout3_8, 'push');
            app.Load8Button.ButtonPushedFcn = createCallbackFcn(app, @Load8ButtonPushed, true);
            app.Load8Button.Text = 'Load 8';
            app.GridLayout3_9 = uigridlayout(app.GridLayout2);
            app.Sample9Button = uibutton(app.GridLayout3_9, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample 9';
            app.Load9Button = uibutton(app.GridLayout3_9, 'push');
            app.Load9Button.ButtonPushedFcn = createCallbackFcn(app, @Load9ButtonPushed, true);
            app.Load9Button.Text = 'Load 9';
            app.SynthesizerPanel = uipanel(app.GridLayout15);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.GridLayout16 = uigridlayout(app.SynthesizerPanel);
            app.GridLayout12_2 = uigridlayout(app.GridLayout16);
            app.DropDown_2 = uidropdown(app.GridLayout12_2);
            app.DropDown_2.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.DropDown_2.Value = 'Sample 1';
            app.SaveButton_2 = uibutton(app.GridLayout12_2, 'push');
            app.SaveButton_2.Text = 'Save';
            app.WaveFunctionButtonGroup = uibuttongroup(app.GridLayout16);
            app.WaveFunctionButtonGroup.Title = 'Wave Function';
            app.SineButton = uiradiobutton(app.WaveFunctionButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.TriangleButton = uiradiobutton(app.WaveFunctionButtonGroup);
            app.TriangleButton.Text = 'Triangle';
            app.SquareButton = uiradiobutton(app.WaveFunctionButtonGroup);
            app.SquareButton.Text = 'Square';
            app.Image = uiimage(app.GridLayout16);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pianodemo.png');
            app.EffectsandModificationsPanel = uipanel(app.GridLayout);
            app.EffectsandModificationsPanel.Title = 'Effects and Modifications';
            app.GridLayout17 = uigridlayout(app.EffectsandModificationsPanel);
            app.GridLayout12_3 = uigridlayout(app.GridLayout17);
            app.DropDown_3 = uidropdown(app.GridLayout12_3);
            app.DropDown_3.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.DropDown_3.Value = 'Sample 1';
            app.SaveButton_3 = uibutton(app.GridLayout12_3, 'push');
            app.SaveButton_3.Text = 'Save';
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.Switch = uiswitch(app.GridLayout18, 'slider');
            app.Switch.Items = {'Reverse', 'Forward'};
            app.Switch.Value = 'Forward';
            app.DirectionLabel = uilabel(app.GridLayout18);
            app.DirectionLabel.Text = 'Direction';
            app.VolumeLabel = uilabel(app.GridLayout18);
            app.VolumeLabel.Text = 'Volume';
            app.Knob = uiknob(app.GridLayout18, 'continuous');
            app.GridLayout19 = uigridlayout(app.GridLayout17);
            app.PlaybackSpeedLabel = uilabel(app.GridLayout19);
            app.PlaybackSpeedLabel.Text = 'Playback Speed';
            app.Slider = uislider(app.GridLayout19);
            app.Slider.Limits = [0.25 2];
            app.Slider.Value = 1;
            app.GridLayout20 = uigridlayout(app.GridLayout17);
            app.DelayandEchoLabel = uilabel(app.GridLayout20);
            app.DelayandEchoLabel.Text = 'Delay and Echo';
            app.GridLayout21 = uigridlayout(app.GridLayout20);
            app.DelayKnobLabel = uilabel(app.GridLayout21);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.GridLayout21, 'continuous');
            app.DelayKnob.Limits = [0 2];
            app.DelayKnob.MajorTicks = [0 0.5 1 1.5 2];
            app.GridLayout21_2 = uigridlayout(app.GridLayout20);
            app.FeedbackKnobLabel = uilabel(app.GridLayout21_2);
            app.FeedbackKnobLabel.Text = 'Feedback';
            app.FeedbackKnob = uiknob(app.GridLayout21_2, 'continuous');
            app.FeedbackKnob.Limits = [0 5];
            app.FeedbackKnob.MajorTicks = [0 1 2 3 4 5];
            app.GridLayout22 = uigridlayout(app.GridLayout17);
            app.EqualizerLabel = uilabel(app.GridLayout22);
            app.EqualizerLabel.Text = 'Equalizer';
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.HzLabel = uilabel(app.GridLayout23);
            app.HzLabel.Text = '62.5Hz';
            app.HzLabel_2 = uilabel(app.GridLayout23);
            app.HzLabel_2.Text = '125Hz';
            app.HzLabel_3 = uilabel(app.GridLayout23);
            app.HzLabel_3.Text = '250Hz';
            app.HzLabel_4 = uilabel(app.GridLayout23);
            app.HzLabel_4.Text = '500Hz';
            app.kHzLabel = uilabel(app.GridLayout23);
            app.kHzLabel.Text = '1kHz';
            app.kHzLabel_2 = uilabel(app.GridLayout23);
            app.kHzLabel_2.Text = '2kHz';
            app.Slider_2 = uislider(app.GridLayout23);
            app.Slider_2.Limits = [-2 2];
            app.Slider_3 = uislider(app.GridLayout23);
            app.Slider_3.Limits = [-2 2];
            app.Slider_4 = uislider(app.GridLayout23);
            app.Slider_4.Limits = [-2 2];
            app.Slider_5 = uislider(app.GridLayout23);
            app.Slider_5.Limits = [-2 2];
            app.Slider_6 = uislider(app.GridLayout23);
            app.Slider_6.Limits = [-2 2];
            app.Slider_7 = uislider(app.GridLayout23);
            app.Slider_7.Limits = [-2 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_gui_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end